classdef monochromatorTriax < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HoribaJobinYvonTriaxMonochromatorUIFigure  matlab.ui.Figure
        PortConnectButtonGroup          matlab.ui.container.ButtonGroup
        GPIBDropDown                    matlab.ui.control.DropDown
        BaudrateDropDown                matlab.ui.control.DropDown
        BaudrateDropDownLabel           matlab.ui.control.Label
        PortDropDown                    matlab.ui.control.DropDown
        PortDropDownLabel               matlab.ui.control.Label
        GPIBButton                      matlab.ui.control.RadioButton
        SerialButton                    matlab.ui.control.RadioButton
        HoribaSynerJYButton             matlab.ui.control.Button
        HoribaHardwareConfigurationandControlButton  matlab.ui.control.Button
        EntranceButtonGroup             matlab.ui.container.ButtonGroup
        EntraceSideButton               matlab.ui.control.RadioButton
        EntranceFrontButton             matlab.ui.control.RadioButton
        InitializePanel                 matlab.ui.container.Panel
        ConnectButton                   matlab.ui.control.StateButton
        LoadConfigDropDown              matlab.ui.control.DropDown
        LoadConfigDropDownLabel         matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelLabel                      matlab.ui.control.Label
        ForceMotorInitializationButton  matlab.ui.control.Button
        ShutterButtonGroup              matlab.ui.container.ButtonGroup
        CloseButton                     matlab.ui.control.RadioButton
        OpenButton                      matlab.ui.control.RadioButton
        CalibrationPanel                matlab.ui.container.Panel
        WavelengthEditField             matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel        matlab.ui.control.Label
        SetValueButton                  matlab.ui.control.Button
        TurretGratingIndexButtonGroup   matlab.ui.container.ButtonGroup
        DiffractionGrating2Button       matlab.ui.control.RadioButton
        DiffractionGrating1Button       matlab.ui.control.RadioButton
        DiffractionGrating0Button       matlab.ui.control.RadioButton
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        ExitButtonGroup                 matlab.ui.container.ButtonGroup
        ExitSideButton                  matlab.ui.control.RadioButton
        ExitFrontButton                 matlab.ui.control.RadioButton
        SlitsWidthPanel                 matlab.ui.container.Panel
        BandwidthnmLabel                matlab.ui.control.Label
        WidthmmLabel                    matlab.ui.control.Label
        ExitFrontBandwidthEditField     matlab.ui.control.NumericEditField
        ExitSideBandwidthEditField      matlab.ui.control.NumericEditField
        EntraceSideBandwidthEditField   matlab.ui.control.NumericEditField
        EntraceFrontBandwidthEditField  matlab.ui.control.NumericEditField
        EntraceSideEditField            matlab.ui.control.NumericEditField
        EntraceSideEditFieldLabel       matlab.ui.control.Label
        ExitSideEditField               matlab.ui.control.NumericEditField
        ExitSideEditFieldLabel          matlab.ui.control.Label
        ExitFrontEditField              matlab.ui.control.NumericEditField
        ExitFrontEditFieldLabel         matlab.ui.control.Label
        EntraceFrontEditField           matlab.ui.control.NumericEditField
        EntraceFrontEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        triax;                          % Monochromator object.
    end
    
    methods (Access = private)
        
        
        function enableElements(app)
            condition = true;

            % Initialize Buttons
            app.ForceMotorInitializationButton.Enable = condition;

            % Wavelength Filed
            app.WavelengthnmEditField.Enable = condition;

            % Slits Width Fields
            app.EntraceFrontEditField.Enable = condition;
            app.EntraceSideEditField.Enable = condition;
            app.ExitFrontEditField.Enable = condition;
            app.ExitSideEditField.Enable = condition;
            
            % Entrance Mirror Buttons
            app.EntranceFrontButton.Enable = condition;
            app.EntraceSideButton.Enable = condition;

            % Exit Mirror Buttons
            app.ExitFrontButton.Enable = condition;
            app.ExitSideButton.Enable = condition;

            % Shutter Buttons
            app.OpenButton.Enable = condition;
            app.CloseButton.Enable = condition;

            % Calibration Fileds
            app.WavelengthEditField.Enable = condition;
            app.SetValueButton.Enable = condition;

            % Grating Turrent Buttons
            app.DiffractionGrating0Button.Enable = condition;
            app.DiffractionGrating1Button.Enable = condition;
            app.DiffractionGrating2Button.Enable = condition;
        end

        function disableElements(app)

            condition = false;

            % Initialize Buttons
            app.ForceMotorInitializationButton.Enable = condition;

            % Wavelength Filed
            app.WavelengthnmEditField.Enable = condition;

            % Slits Width Fields
            app.EntraceFrontEditField.Enable = condition;
            app.EntraceSideEditField.Enable = condition;
            app.ExitFrontEditField.Enable = condition;
            app.ExitSideEditField.Enable = condition;
            
            % Entrance Mirror Buttons
            app.EntranceFrontButton.Enable = condition;
            app.EntraceSideButton.Enable = condition;

            % Exit Mirror Buttons
            app.ExitFrontButton.Enable = condition;
            app.ExitSideButton.Enable = condition;

            % Shutter Buttons
            app.OpenButton.Enable = condition;
            app.CloseButton.Enable = condition;

            % Calibration Fileds
            app.WavelengthEditField.Enable = condition;
            app.SetValueButton.Enable = condition;

            % Grating Turrent Buttons
            app.DiffractionGrating0Button.Enable = condition;
            app.DiffractionGrating1Button.Enable = condition;
            app.DiffractionGrating2Button.Enable = condition;
            
        end
        
        function getInformationDevice(app)

            % Get Current Wavelength
            wavelength = app.triax.getWavelengthPosition();
            app.WavelengthnmEditField.Value = wavelength;
            
            % Get Slit Entrance Front Width
            slit0Width = app.triax.getSlitWidth(0);
            app.EntraceFrontEditField.Value = slit0Width;
            
            % Get Slit Entrance Side Width
            slit1Width = app.triax.getSlitWidth(1);
            app.EntraceSideEditField.Value = slit1Width;
            
            % Get Slit Exit Front Width
            slit2Width = app.triax.getSlitWidth(2);
            app.ExitFrontEditField.Value = slit2Width;
            
            % Get Slit Exit Side Width
            slit3Width = app.triax.getSlitWidth(3);
            app.ExitSideEditField.Value = slit3Width;
            
            % Get Turret Grating Index
            turretIndex = app.triax.getGratingIndex();
            switch turretIndex
                case 0
                    app.DiffractionGrating0Button.Value = 1;
                case 1
                    app.DiffractionGrating1Button.Value = 1;
                case 2
                    app.DiffractionGrating2Button.Value = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Add Current Path to Matlab
            addpath(pwd);

            % Search File Class myTriax
            if exist('myTriax.m', 'file')

                % Search Serial Ports available
                try                                                 % Return list (string array) with serial ports connected
                    listSerialPorts = serialportlist;               % New function introduced Matlab R2019b: serialportlist
                catch
                    listSerialPorts = seriallist;                   % Function introduced Matlab R2017a
                end
                
                % update
                app.PortDropDown.Items = listSerialPorts;
                
                % Enable Button to Access Software Horiba Hardware Configuration and Control
                if exist('C:\Program Files (x86)\Jobin Yvon\Hardware Configuration and Control\HW Config And Control.exe',"file")
                    app.HoribaHardwareConfigurationandControlButton.Enable = true;
                else
                    app.HoribaHardwareConfigurationandControlButton.Enable = false;
                end
                
                % Enable Button to Access Software Horiba SynerJY
                if exist('C:\Program Files (x86)\Jobin Yvon\',"file")
                    app.HoribaSynerJYButton.Enable = true;
                else
                    app.HoribaSynerJYButton.Enable = false;
                end
            else
                errordlg('Class file "myTriax.m" not found in the current path.');
            end
        end

        % Close request function: HoribaJobinYvonTriaxMonochromatorUIFigure
        function HoribaJobinYvonTriaxMonochromatorUIFigureCloseRequest(app, event)
            
            if ~isempty(app.triax)
                app.triax.disconnect();
            end
            
            delete(app)
            
        end

        % Value changed function: WavelengthnmEditField
        function WavelengthnmEditFieldValueChanged(app, event)
            
            app.disableElements();
            pause(0.1);

            value = app.WavelengthnmEditField.Value;
            app.triax.moveTo(value);

            app.enableElements();
            
        end

        % Callback function
        function ConnectButtonPushed(app, event)
            
            % Create Monochromator Triax Object
            valueModel = app.ModelDropDown.Value;
            app.triax = myTriax(valueModel);
            
            % Establish Connection with the Device
            valueSerialPort = app.PortDropDown.Value;
            valueSerialBaudrate = str2double(app.BaudrateDropDown.Value);
            app.triax.connect('Port',valueSerialPort,'Baudrate',valueSerialBaudrate);
            
            % Enable App Elements
            condition = true;
            app.DisconnectButton.Enable = condition;
            app.ForceMotorInitializationButton.Enable = condition;
            app.WavelengthnmEditField.Enable = condition;
            app.WavelengthEditFieldLabel.Enable = condition;
            app.EntraceFrontEditField.Enable = condition;
            app.EntraceFrontEditFieldLabel.Enable = condition;
            app.EntraceSideEditField.Enable = condition;
            app.EntraceSideEditFieldLabel.Enable = condition;
            app.ExitFrontEditField.Enable = condition;
            app.ExitFrontEditFieldLabel.Enable = condition;
            app.ExitSideEditField.Enable = condition;
            app.ExitSideEditFieldLabel.Enable = condition;
            app.EntranceFrontButton.Enable = condition;
            app.EntraceSideButton.Enable = condition;
            app.ExitFrontButton.Enable = condition;
            app.ExitSideButton.Enable = condition;
            app.OpenButton.Enable = condition;
            app.CloseButton.Enable = condition;
            app.DiffractionGrating0Button.Enable = condition;
            app.DiffractionGrating1Button.Enable = condition;
            app.DiffractionGrating2Button.Enable = condition;
            app.WavelengthEditField.Enable = condition;
            app.SetValueButton.Enable = condition;
            
            % Get Information from Device
                app.getInformationDevice();
                
            % Set 
                % Set Entrace Front
                app.triax.entranceMirrorFront();
                
                % Set Exit Front
                app.triax.exitMirrorFront();
                
                % Set Shutter Close
                app.triax.closeShutter();


        end

        % Callback function
        function DisconnectButtonPushed(app, event)
            
            app.triax.disconnect();
            
            condition = false;
            app.DisconnectButton.Enable = condition;
            app.ForceMotorInitializationButton.Enable = condition;
            app.WavelengthnmEditField.Enable = condition;
            app.WavelengthEditFieldLabel.Enable = condition;
            app.EntraceFrontEditField.Enable = condition;
            app.EntraceFrontEditFieldLabel.Enable = condition;
            app.EntraceSideEditField.Enable = condition;
            app.EntraceSideEditFieldLabel.Enable = condition;
            app.ExitFrontEditField.Enable = condition;
            app.ExitFrontEditFieldLabel.Enable = condition;
            app.ExitSideEditField.Enable = condition;
            app.ExitSideEditFieldLabel.Enable = condition;
            app.EntranceFrontButton.Enable = condition;
            app.EntraceSideButton.Enable = condition;
            app.ExitFrontButton.Enable = condition;
            app.ExitSideButton.Enable = condition;
            app.OpenButton.Enable = condition;
            app.CloseButton.Enable = condition;
            app.DiffractionGrating0Button.Enable = condition;
            app.DiffractionGrating1Button.Enable = condition;
            app.DiffractionGrating2Button.Enable = condition;
            app.WavelengthEditField.Enable = condition;
            app.SetValueButton.Enable = condition;
            
        end

        % Button pushed function: ForceMotorInitializationButton
        function ForceMotorInitializationButtonPushed(app, event)
            
            % Disable Emelents
            app.disableElements();

            % Initialize Motors
            app.triax.isinitialized = false;
            app.triax.initializeMotors();

            % Get Monochromator Values
            app.getInformationDevice();

            % Enable Elements
            app.enableElements();
            
        end

        % Callback function
        function EntranceButtonGroupSelectionChanged(app, event)
            
            app.disableElements();

            if app.FrontButton.Value
                app.triax.entranceMirrorFront();
            elseif app.SideButton.Value
                app.triax.entranceMirrorSide();
            end
            
            app.enableElements();
        end

        % Selection changed function: ExitButtonGroup
        function ExitButtonGroupSelectionChanged(app, event)
            
            app.disableElements();

            if app.ExitFrontButton.Value
                app.triax.exitMirrorFront();
            elseif app.ExitSideButton.Value
                app.triax.exitMirrorSide();
            end

            app.enableElements();
        end

        % Selection changed function: ShutterButtonGroup
        function ShutterButtonGroupSelectionChanged(app, event)
            
            app.disableElements();

            if app.OpenButton.Value
                app.triax.exitMirrorFront();
            elseif app.CloseButton.Value
                app.triax.exitMirrorSide();
            end
            
            app.enableElements();
        end

        % Selection changed function: TurretGratingIndexButtonGroup
        function TurretGratingIndexButtonGroupSelectionChanged(app, event)
            
            % Disable GUI Elements
            app.disableElements();

            % Change Grating
            if app.DiffractionGrating0Button.Value
                app.triax.moveGratingIndex(0);
            elseif app.DiffractionGrating1Button.Value
                app.triax.moveGratingIndex(1);
            elseif app.DiffractionGrating2Button.Value
                app.triax.moveGratingIndex(2);
            end

            % Get Monochromator Values
            app.getInformationDevice();
            
            % Enable GUI Elements
            app.enableElements();
        end

        % Value changed function: EntraceFrontEditField
        function EntraceFrontEditFieldValueChanged(app, event)
            
            app.disableElements();
            pause(0.1);

            value = app.EntraceFrontEditField.Value;
            app.triax.moveSlitWidth(0,value);

            app.enableElements();
            
        end

        % Value changed function: ExitFrontEditField
        function ExitFrontEditFieldValueChanged(app, event)
            
            app.disableElements();
            pause(0.1);

            value = app.ExitFrontEditField.Value;
            app.triax.moveSlitWidth(2,value);

            app.enableElements();
            
        end

        % Value changed function: ExitSideEditField
        function ExitSideEditFieldValueChanged(app, event)
            
            app.disableElements();
            pause(0.1);

            value = app.ExitSideEditField.Value;
            app.triax.moveSlitWidth(3,value);

            app.enableElements();
            
        end

        % Value changed function: EntraceSideEditField
        function EntraceSideEditFieldValueChanged(app, event)
            
            app.disableElements();
            pause(0.1);

            value = app.EntraceSideEditField.Value;
            app.triax.moveSlitWidth(1,value);

            app.enableElements();
            
        end

        % Button pushed function: SetValueButton
        function SetValueButtonPushed(app, event)
            
            value = app.WavelengthEditField.Value;
            app.triax.setWavelengthPosition(value);
            
        end

        % Selection changed function: EntranceButtonGroup
        function EntranceButtonGroupSelectionChanged2(app, event)
            
            app.disableElements();

            if app.EntranceFrontButton.Value
                app.triax.entranceMirrorFront();
            elseif app.EntraceSideButton.Value
                app.triax.entranceMirrorSide();
            end

            app.enableElements();
            
        end

        % Button pushed function: 
        % HoribaHardwareConfigurationandControlButton
        function HoribaHardwareConfigurationandControlButtonPushed(app, event)
            
            open('C:\Program Files (x86)\Jobin Yvon\Hardware Configuration and Control\HW Config And Control.exe');
            
        end

        % Selection changed function: PortConnectButtonGroup
        function PortConnectButtonGroupSelectionChanged(app, event)
            
            if app.SerialButton.Value
                conditionA = true;
            else
                conditionA = false;
            end
            
            app.PortDropDown.Enable = conditionA;
            app.PortDropDownLabel.Enable = conditionA;
            app.BaudrateDropDown.Enable = conditionA;
            app.BaudrateDropDownLabel.Enable = conditionA;
            
            % if app.GPIBButton.Value
            %     conditionB = true;
            % else
            %     conditionB = false;
            % end
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            
            if value

                % Change Name
                app.ConnectButton.Text = 'Disconnect';
                
                % Disable Elements
                condition = false;

                app.SerialButton.Enable = condition;
                app.PortDropDown.Enable = condition;
                app.BaudrateDropDown.Enable = condition;
                app.GPIBButton.Enable = condition;

                app.ModelDropDown.Enable = condition;

                app.HoribaSynerJYButton.Enable = condition;
                app.HoribaHardwareConfigurationandControlButton.Enable = condition;

                % Create Monochromator Triax Object
                valueModel = app.ModelDropDown.Value;
                app.triax = myTriax(valueModel);
                
                % Establish Connection with the Device
                valueSerialPort = app.PortDropDown.Value;
                valueSerialBaudrate = str2double(app.BaudrateDropDown.Value);
                app.triax.connect('Port',valueSerialPort,'Baudrate',valueSerialBaudrate);
                
                % Enable App Elements
                condition = true;
                app.ForceMotorInitializationButton.Enable = condition;
                app.WavelengthnmEditField.Enable = condition;
                app.WavelengthnmEditFieldLabel.Enable = condition;
                app.EntraceFrontEditField.Enable = condition;
                app.EntraceFrontEditFieldLabel.Enable = condition;
                app.EntraceSideEditField.Enable = condition;
                app.EntraceSideEditFieldLabel.Enable = condition;
                app.ExitFrontEditField.Enable = condition;
                app.ExitFrontEditFieldLabel.Enable = condition;
                app.ExitSideEditField.Enable = condition;
                app.ExitSideEditFieldLabel.Enable = condition;
                app.EntranceFrontButton.Enable = condition;
                app.EntraceSideButton.Enable = condition;
                app.ExitFrontButton.Enable = condition;
                app.ExitSideButton.Enable = condition;
                app.OpenButton.Enable = condition;
                app.CloseButton.Enable = condition;
                app.DiffractionGrating0Button.Enable = condition;
                app.DiffractionGrating1Button.Enable = condition;
                app.DiffractionGrating2Button.Enable = condition;
                app.WavelengthEditField.Enable = condition;
                app.SetValueButton.Enable = condition;
                
                % Get Information from Device
                app.getInformationDevice();
                    
                % Set 
                    % Set Entrace Front
                    app.triax.entranceMirrorFront();
                    
                    % Set Exit Front
                    app.triax.exitMirrorFront();
                    
                    % Set Shutter Close
                    app.triax.closeShutter();
                
            else
                % Change Name
                app.ConnectButton.Text = 'Connect';

                % Disable Elements
                condition = false;
                app.ForceMotorInitializationButton.Enable = condition;
                app.WavelengthnmEditField.Enable = condition;
                app.WavelengthnmEditFieldLabel.Enable = condition;
                app.EntraceFrontEditField.Enable = condition;
                app.EntraceFrontEditFieldLabel.Enable = condition;
                app.EntraceSideEditField.Enable = condition;
                app.EntraceSideEditFieldLabel.Enable = condition;
                app.ExitFrontEditField.Enable = condition;
                app.ExitFrontEditFieldLabel.Enable = condition;
                app.ExitSideEditField.Enable = condition;
                app.ExitSideEditFieldLabel.Enable = condition;
                app.EntranceFrontButton.Enable = condition;
                app.EntraceSideButton.Enable = condition;
                app.ExitFrontButton.Enable = condition;
                app.ExitSideButton.Enable = condition;
                app.OpenButton.Enable = condition;
                app.CloseButton.Enable = condition;
                app.DiffractionGrating0Button.Enable = condition;
                app.DiffractionGrating1Button.Enable = condition;
                app.DiffractionGrating2Button.Enable = condition;
                app.WavelengthEditField.Enable = condition;
                app.SetValueButton.Enable = condition;
                
                % Enable Elements
                condition = true;

                app.SerialButton.Enable = condition;
                app.PortDropDown.Enable = condition;
                app.BaudrateDropDown.Enable = condition;
                app.GPIBButton.Enable = condition;

                app.ModelDropDown.Enable = condition;

                % Enable Button to Access Software Horiba Hardware Configuration and Control
                if exist('C:\Program Files (x86)\Jobin Yvon\Hardware Configuration and Control\HW Config And Control.exe',"file")
                    app.HoribaHardwareConfigurationandControlButton.Enable = true;
                else
                    app.HoribaHardwareConfigurationandControlButton.Enable = false;
                end
                
                %Enable Button to Access Software Horiba SynerJY
                if exist('C:\Program Files (x86)\Jobin Yvon\',"file")
                    app.HoribaSynerJYButton.Enable = true;
                else
                    app.HoribaSynerJYButton.Enable = false;
                end

                % Disconnect Monochromator
                app.triax.disconnect();

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HoribaJobinYvonTriaxMonochromatorUIFigure = uifigure('Visible', 'off');
            app.HoribaJobinYvonTriaxMonochromatorUIFigure.Name = 'Horiba Jobin-Yvon Triax Monochromator';
            app.HoribaJobinYvonTriaxMonochromatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @HoribaJobinYvonTriaxMonochromatorUIFigureCloseRequest, true);
            app.SlitsWidthPanel = uipanel(app.HoribaJobinYvonTriaxMonochromatorUIFigure);
            app.SlitsWidthPanel.Title = 'Slits Width';
            app.EntraceFrontEditFieldLabel = uilabel(app.SlitsWidthPanel);
            app.EntraceFrontEditFieldLabel.Enable = 'off';
            app.EntraceFrontEditFieldLabel.Text = 'Entrace Front';
            app.EntraceFrontEditField = uieditfield(app.SlitsWidthPanel, 'numeric');
            app.EntraceFrontEditField.Limits = [0 2.24];
            app.EntraceFrontEditField.ValueChangedFcn = createCallbackFcn(app, @EntraceFrontEditFieldValueChanged, true);
            app.EntraceFrontEditField.Enable = 'off';
            app.ExitFrontEditFieldLabel = uilabel(app.SlitsWidthPanel);
            app.ExitFrontEditFieldLabel.Enable = 'off';
            app.ExitFrontEditFieldLabel.Text = 'Exit Front';
            app.ExitFrontEditField = uieditfield(app.SlitsWidthPanel, 'numeric');
            app.ExitFrontEditField.Limits = [0 2.24];
            app.ExitFrontEditField.ValueChangedFcn = createCallbackFcn(app, @ExitFrontEditFieldValueChanged, true);
            app.ExitFrontEditField.Enable = 'off';
            app.ExitSideEditFieldLabel = uilabel(app.SlitsWidthPanel);
            app.ExitSideEditFieldLabel.Enable = 'off';
            app.ExitSideEditFieldLabel.Text = 'Exit Side';
            app.ExitSideEditField = uieditfield(app.SlitsWidthPanel, 'numeric');
            app.ExitSideEditField.Limits = [0 2.24];
            app.ExitSideEditField.ValueChangedFcn = createCallbackFcn(app, @ExitSideEditFieldValueChanged, true);
            app.ExitSideEditField.Enable = 'off';
            app.EntraceSideEditFieldLabel = uilabel(app.SlitsWidthPanel);
            app.EntraceSideEditFieldLabel.Enable = 'off';
            app.EntraceSideEditFieldLabel.Text = 'Entrace Side';
            app.EntraceSideEditField = uieditfield(app.SlitsWidthPanel, 'numeric');
            app.EntraceSideEditField.Limits = [0 2.24];
            app.EntraceSideEditField.ValueChangedFcn = createCallbackFcn(app, @EntraceSideEditFieldValueChanged, true);
            app.EntraceSideEditField.Enable = 'off';
            app.EntraceFrontBandwidthEditField = uieditfield(app.SlitsWidthPanel, 'numeric');
            app.EntraceFrontBandwidthEditField.Enable = 'off';
            app.EntraceSideBandwidthEditField = uieditfield(app.SlitsWidthPanel, 'numeric');
            app.EntraceSideBandwidthEditField.Enable = 'off';
            app.ExitSideBandwidthEditField = uieditfield(app.SlitsWidthPanel, 'numeric');
            app.ExitSideBandwidthEditField.Enable = 'off';
            app.ExitFrontBandwidthEditField = uieditfield(app.SlitsWidthPanel, 'numeric');
            app.ExitFrontBandwidthEditField.Enable = 'off';
            app.WidthmmLabel = uilabel(app.SlitsWidthPanel);
            app.WidthmmLabel.Text = {'Width'; '(mm)'};
            app.BandwidthnmLabel = uilabel(app.SlitsWidthPanel);
            app.BandwidthnmLabel.Text = {'Bandwidth'; '(nm)'};
            app.ExitButtonGroup = uibuttongroup(app.HoribaJobinYvonTriaxMonochromatorUIFigure);
            app.ExitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExitButtonGroupSelectionChanged, true);
            app.ExitButtonGroup.Title = 'Exit';
            app.ExitFrontButton = uiradiobutton(app.ExitButtonGroup);
            app.ExitFrontButton.Enable = 'off';
            app.ExitFrontButton.Text = 'Front';
            app.ExitFrontButton.Value = true;
            app.ExitSideButton = uiradiobutton(app.ExitButtonGroup);
            app.ExitSideButton.Enable = 'off';
            app.ExitSideButton.Text = 'Side';
            app.WavelengthnmEditFieldLabel = uilabel(app.HoribaJobinYvonTriaxMonochromatorUIFigure);
            app.WavelengthnmEditFieldLabel.Enable = 'off';
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm):';
            app.WavelengthnmEditField = uieditfield(app.HoribaJobinYvonTriaxMonochromatorUIFigure, 'numeric');
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditFieldValueChanged, true);
            app.WavelengthnmEditField.Enable = 'off';
            app.TurretGratingIndexButtonGroup = uibuttongroup(app.HoribaJobinYvonTriaxMonochromatorUIFigure);
            app.TurretGratingIndexButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TurretGratingIndexButtonGroupSelectionChanged, true);
            app.TurretGratingIndexButtonGroup.Title = 'Turret Grating Index';
            app.DiffractionGrating0Button = uiradiobutton(app.TurretGratingIndexButtonGroup);
            app.DiffractionGrating0Button.Enable = 'off';
            app.DiffractionGrating0Button.Text = 'Diffraction Grating #0';
            app.DiffractionGrating0Button.Value = true;
            app.DiffractionGrating1Button = uiradiobutton(app.TurretGratingIndexButtonGroup);
            app.DiffractionGrating1Button.Enable = 'off';
            app.DiffractionGrating1Button.Text = 'Diffraction Grating #1';
            app.DiffractionGrating2Button = uiradiobutton(app.TurretGratingIndexButtonGroup);
            app.DiffractionGrating2Button.Enable = 'off';
            app.DiffractionGrating2Button.Text = 'Diffraction Grating #2';
            app.CalibrationPanel = uipanel(app.HoribaJobinYvonTriaxMonochromatorUIFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.SetValueButton = uibutton(app.CalibrationPanel, 'push');
            app.SetValueButton.ButtonPushedFcn = createCallbackFcn(app, @SetValueButtonPushed, true);
            app.SetValueButton.Enable = 'off';
            app.SetValueButton.Text = 'Set Value';
            app.WavelengthEditFieldLabel = uilabel(app.CalibrationPanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.WavelengthEditField.Enable = 'off';
            app.ShutterButtonGroup = uibuttongroup(app.HoribaJobinYvonTriaxMonochromatorUIFigure);
            app.ShutterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShutterButtonGroupSelectionChanged, true);
            app.ShutterButtonGroup.Title = 'Shutter';
            app.OpenButton = uiradiobutton(app.ShutterButtonGroup);
            app.OpenButton.Enable = 'off';
            app.OpenButton.Text = 'Open';
            app.CloseButton = uiradiobutton(app.ShutterButtonGroup);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Text = 'Close';
            app.CloseButton.Value = true;
            app.InitializePanel = uipanel(app.HoribaJobinYvonTriaxMonochromatorUIFigure);
            app.InitializePanel.Title = 'Initialize';
            app.ForceMotorInitializationButton = uibutton(app.InitializePanel, 'push');
            app.ForceMotorInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @ForceMotorInitializationButtonPushed, true);
            app.ForceMotorInitializationButton.Enable = 'off';
            app.ForceMotorInitializationButton.Text = {'Force Motor'; 'Initialization'};
            app.ModelLabel = uilabel(app.InitializePanel);
            app.ModelLabel.Text = 'Model:';
            app.ModelDropDown = uidropdown(app.InitializePanel);
            app.ModelDropDown.Items = {'Triax 180', 'Triax 190', 'Triax 320', 'Triax 550'};
            app.ModelDropDown.Value = 'Triax 320';
            app.LoadConfigDropDownLabel = uilabel(app.InitializePanel);
            app.LoadConfigDropDownLabel.Text = 'Load Config';
            app.LoadConfigDropDown = uidropdown(app.InitializePanel);
            app.LoadConfigDropDown.Items = {'None'};
            app.LoadConfigDropDown.Enable = 'off';
            app.LoadConfigDropDown.Value = 'None';
            app.ConnectButton = uibutton(app.InitializePanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.EntranceButtonGroup = uibuttongroup(app.HoribaJobinYvonTriaxMonochromatorUIFigure);
            app.EntranceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EntranceButtonGroupSelectionChanged2, true);
            app.EntranceButtonGroup.Title = 'Entrance';
            app.EntranceFrontButton = uiradiobutton(app.EntranceButtonGroup);
            app.EntranceFrontButton.Enable = 'off';
            app.EntranceFrontButton.Text = 'Front';
            app.EntranceFrontButton.Value = true;
            app.EntraceSideButton = uiradiobutton(app.EntranceButtonGroup);
            app.EntraceSideButton.Enable = 'off';
            app.EntraceSideButton.Text = 'Side';
            app.HoribaHardwareConfigurationandControlButton = uibutton(app.HoribaJobinYvonTriaxMonochromatorUIFigure, 'push');
            app.HoribaHardwareConfigurationandControlButton.ButtonPushedFcn = createCallbackFcn(app, @HoribaHardwareConfigurationandControlButtonPushed, true);
            app.HoribaHardwareConfigurationandControlButton.Enable = 'off';
            app.HoribaHardwareConfigurationandControlButton.Text = {'Horiba Hardware'; 'Configuration and Control '};
            app.HoribaSynerJYButton = uibutton(app.HoribaJobinYvonTriaxMonochromatorUIFigure, 'push');
            app.HoribaSynerJYButton.Enable = 'off';
            app.HoribaSynerJYButton.Text = 'Horiba SynerJY';
            app.PortConnectButtonGroup = uibuttongroup(app.HoribaJobinYvonTriaxMonochromatorUIFigure);
            app.PortConnectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PortConnectButtonGroupSelectionChanged, true);
            app.PortConnectButtonGroup.Title = 'Port Connect.';
            app.SerialButton = uiradiobutton(app.PortConnectButtonGroup);
            app.SerialButton.Text = 'Serial';
            app.SerialButton.Value = true;
            app.GPIBButton = uiradiobutton(app.PortConnectButtonGroup);
            app.GPIBButton.Enable = 'off';
            app.GPIBButton.Text = 'GPIB';
            app.PortDropDownLabel = uilabel(app.PortConnectButtonGroup);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.PortConnectButtonGroup);
            app.PortDropDown.Items = {'COM1'};
            app.PortDropDown.Value = 'COM1';
            app.BaudrateDropDownLabel = uilabel(app.PortConnectButtonGroup);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.PortConnectButtonGroup);
            app.BaudrateDropDown.Items = {'1200', '2400', '4800', '9600', '19200'};
            app.BaudrateDropDown.Value = '19200';
            app.GPIBDropDown = uidropdown(app.PortConnectButtonGroup);
            app.GPIBDropDown.Items = {'none'};
            app.GPIBDropDown.Enable = 'off';
            app.GPIBDropDown.Value = 'none';
            % Show the figure after all components are created
            app.HoribaJobinYvonTriaxMonochromatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = monochromatorTriax

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HoribaJobinYvonTriaxMonochromatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HoribaJobinYvonTriaxMonochromatorUIFigure)
        end
    end
end